classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DOBOTJoystickControlSwitch     matlab.ui.control.ToggleSwitch
        DOBOTJoystickControlSwitchLabel  matlab.ui.control.Label
        Q5Slider_2                     matlab.ui.control.Slider
        Q5Slider_2Label                matlab.ui.control.Label
        Q4Slider_2                     matlab.ui.control.Slider
        Q4Slider_2Label                matlab.ui.control.Label
        Q3Slider_2                     matlab.ui.control.Slider
        Q3Slider_2Label                matlab.ui.control.Label
        Q2Slider_2                     matlab.ui.control.Slider
        Q2Slider_2Label                matlab.ui.control.Label
        Q1Slider_2                     matlab.ui.control.Slider
        Q1Slider_2Label                matlab.ui.control.Label
        CondimentOptionsDropDownLabel_3  matlab.ui.control.Label
        StartSimulationButton          matlab.ui.control.Button
        EStopSwitch                    matlab.ui.control.Switch
        EStopSwitchLabel               matlab.ui.control.Label
        CondimentOptionsDropDownLabel_2  matlab.ui.control.Label
        Q5Slider                       matlab.ui.control.Slider
        Q5SliderLabel                  matlab.ui.control.Label
        Q4Slider                       matlab.ui.control.Slider
        Q4SliderLabel                  matlab.ui.control.Label
        PickUpTargetButton             matlab.ui.control.Button
        CondimentOptionsDropDown       matlab.ui.control.DropDown
        CondimentOptionsDropDownLabel  matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        Label                          matlab.ui.control.Label
        Q3Slider                       matlab.ui.control.Slider
        Q3SliderLabel                  matlab.ui.control.Label
        Q2Slider                       matlab.ui.control.Slider
        Q2SliderLabel                  matlab.ui.control.Label
        Q1Slider                       matlab.ui.control.Slider
        Q1SliderLabel                  matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        dobot; % Hold the dobot object
        xarm;
        bowl;
        obstacle;
        coffee;
        Red;
        Yellow;
        Brown;
        check = 1;
        ifBlocked = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
                placeEnvironment();
                % % Load Boxes ----------------------------------------------------------------------------------------
                disp('Loading Boxes.... ');
                PlaceObject("Redbox.ply",[-0.05,-0.01,0]);
                PlaceObject("Yellowbox.ply",[0.10,-0.01,0]);
                PlaceObject("Brownbox.ply",[0.01,-0.01,0]);
                
                
                %% Loading Sauce & bowl ----------------------------------------------------
                disp('Loading Condiments & bowl & Coffee.... ');
                app.Red = RedCondiment(transl(0.005,-0.22,0.75));
                % Red{2} = RedCondiment(transl(0.005,-0.22,0.77));
                % Red{1} = RedCondiment(transl(0.005,-0.22,0.79));
                app.Yellow = YellowCondiment(transl(0.085,-0.22,0.75));
                % Yellow{2} = YellowCondiment(transl(0.085,-0.22,0.77));
                % Yellow{1} = YellowCondiment(transl(0.085,-0.22,0.79));
                app.Brown = BrownCondiment(transl(-0.075,-0.22,0.75));
                % Brown{2} = BrownCondiment(transl(-0.075,-0.22,0.77));
                % Brown{1} = BrownCondiment(transl(-0.075,-0.22,0.79));
                app.bowl = Bowl(transl(0.25,0,0.78));
                app.obstacle = Obstacle(transl(0,-0.25,1.3));
                %app.coffee = Coffee(transl(1.1, -0.4, 0.81));
                
                hold off
                
                %% Loading Robots ----------------------------------------------------------------------------------------
                disp('Loading Robots.... ');
                app.dobot = DobotM();
                
                app.xarm = xArm5;
                
                view([-130, 27]);   % Changing the camera angle 
                disp('Done Loading Environment!')
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            main_simulation(app.dobot, app.xarm, app.Red, app.Yellow, app.Brown, app.bowl, app.obstacle, app.coffee);
        end

        % Value changed function: DOBOTJoystickControlSwitch
        function DOBOTJoystickControlSwitchValueChanged(app, event)
            value = app.DOBOTJoystickControlSwitch.Value;
            while value(2) == "n"
                value = app.DOBOTJoystickControlSwitch.Value;
                % setup joystick
                id = 5;               % Note: may need to be changed if multiple joysticks present
                joy = vrjoystick(id);
                caps(joy) % display joystick information

                % start control
                q = app.dobot.model.getpos;                 % Set initial robot configuration 'q'

                HF = figure(1);         % Initialise figure to display robot

                set(HF,'Position',[0.1 0.1 0.8 0.8]);

                duration = 300;  % Set duration of the simulation (seconds)
                dt = 0.3;      % Set time step for simulation (seconds)

                n = 0;  % Initialise step count to zero
                tic;    % recording simulation start time
                while( toc < duration && value(2) == "n")
                    value = app.DOBOTJoystickControlSwitch.Value;
                    n=n+1; % increment step count

                    % read joystick
                    [axes, buttons, povs] = read(joy);

                    Kv = 0.3; % linear velocity gain
                    Kw = 0.8; % angular velocity gain

                    vx = Kv*axes(1);
                    vy = Kv*axes(2);
                    vz = Kv*(buttons(5)-buttons(7));

                    wx = Kw*axes(4);
                    wy = Kw*axes(3);
                    wz = Kw*(buttons(6)-buttons(8));

                    dx = [vx;vy;vz;wx;wy;wz]; % combined velocity vector

                    % 2 - use DLS J inverse to calculate joint velocity
                    lambda = 0.5;
                    J = app.dobot.model.jacob0(q);
                    Jinv_dls = inv((J'*J)+lambda^2*eye(5))*J';
                    dq = Jinv_dls*dx;

                    % 3 - apply joint velocity to step robot joint angles
                    q = q + dq'*dt;

                    % -------------------------------------------------------------

                    % Update plot
                    app.dobot.model.animate(q);
                    drawnow();
                    % wait until loop time elapsed
                    if (toc > dt*n)
                        warning('Loop %i took too much time - consider increating dt',n);
                    end
                    while (toc < dt*n) % wait until loop time (dt) has elapsed
                    end
                end
            end
            
        end

        % Value changed function: Q1Slider
        function Q1SliderValueChanged(app, event)
              q1 = deg2rad(app.Q1Slider.Value);
              q2 = app.dobot.model.getpos;
              q2(1) = q1;
              app.dobot.model.animate(q2); 
        end

        % Value changed function: Q2Slider
        function Q2SliderValueChanged(app, event)
              q1 = deg2rad(app.Q2Slider.Value);
              q2 = app.dobot.model.getpos;
              q2(2) = q1;
              app.dobot.model.animate(q2);   
        end

        % Value changed function: Q3Slider
        function Q3SliderValueChanged(app, event)
              q1 = deg2rad(app.Q3Slider.Value);
              q2 = app.dobot.model.getpos;
              q2(3) = q1;
              app.dobot.model.animate(q2);      
        end

        % Value changed function: Q4Slider
        function Q4SliderValueChanged(app, event)
              q1 = deg2rad(app.Q4Slider.Value);
              q2 = app.dobot.model.getpos;
              q2(4) = q1;
              app.dobot.model.animate(q2); 
        end

        % Value changed function: Q5Slider
        function Q5SliderValueChanged(app, event)
              q1 = deg2rad(app.Q5Slider.Value);
              q2 = app.dobot.model.getpos;
              q2(5) = q1;
              app.dobot.model.animate(q2); 
            
        end

        % Value changed function: Q1Slider_2
        function Q1Slider_2ValueChanged(app, event)
            value = app.Q1Slider_2.Value;
              q = app.xarm.model.getpos;
              q(1) = deg2rad(value);
              app.xarm.model.animate(q); 
        end

        % Value changed function: Q2Slider_2
        function Q2Slider_2ValueChanged(app, event)
            value = app.Q2Slider_2.Value;
              q = app.xarm.model.getpos;
              q(2) = deg2rad(value);
              app.xarm.model.animate(q); 
        end

        % Value changed function: Q3Slider_2
        function Q3Slider_2ValueChanged(app, event)
            value = app.Q3Slider_2.Value;
            q = app.xarm.model.getpos;
            q(3) = deg2rad(value);
            app.xarm.model.animate(q); 
        end

        % Value changed function: Q4Slider_2
        function Q4Slider_2ValueChanged(app, event)
            value = app.Q4Slider_2.Value;
            q = app.xarm.model.getpos;
            q(4) = deg2rad(value);
            app.xarm.model.animate(q); 
        end

        % Value changed function: Q5Slider_2
        function Q5Slider_2ValueChanged(app, event)
            value = app.Q5Slider_2.Value;
            q = app.xarm.model.getpos;
            q(5) = deg2rad(value);
            app.xarm.model.animate(q); 
        end

        % Value changed function: CondimentOptionsDropDown
        function CondimentOptionsDropDownValueChanged(app, event)
            value = app.CondimentOptionsDropDown.Value;
            if value == "On"
                app.dobot.LightCurtain;
            end
            if value == "Off"
                app.dobot.TurnOffCurtain;
            end
        end

        % Value changed function: EStopSwitch
        function EStopSwitchValueChanged(app, event)
            value = app.EStopSwitch.Value;
            if (value(2) == 'n')
                id = 5;
                joy = vrjoystick(id);
                check = 1;
                disp("ESTOP HAS BEEN ENGAGED");
                app.Lamp.Color = [0.83, 0.048, 0.101];
                joystick1(app.obstacle, joy);
                while (check == 1)
                    value = app.EStopSwitch.Value;
                    joystick1(app.obstacle, joy);
                    if (value(2) == 'f')
                        disp("ESTOP HAS BEEN DISENGAGED");
                        check = 0;
                        app.Lamp.Color = [0.3922, 0.8314, 0.0745];
                        joystick1(app.obstacle, joy);
                    end
                    pause(0.05);
                end
                input("Press Enter to Continue");

            end
            
        end

        % Button pushed function: PickUpTargetButton
        function PickUpTargetButtonPushed(app, event)
            value = app.CondimentOptionsDropDown.Value;

            q0 = [0 pi/4 pi/4 0];

            q1 = [-1.1943 0.0868 -0.2077 0];

            q2 = [0.4324 0.4943 -0.2041 0];

            qC{1} = [-1.4373 0.9005 1.1431 0]; % Start Position of Red Condiment

            qC{2} = [-1.1111 1.0054 1.0071 0]; % Start Position of Yellow Condiment

            qC{3} = [-0.8639 1.2014 0.7282 0]; % Start Position of Brown Condiment

            qE{1} = [0.4075 1.0751 0.6979 0]; % End Position of Red Condiment

            qE{2} = [0.4066 0.9648 0.6229 0]; % End Position of Red Condiment

            qE{3} = [0.3811 0.8260 0.5593 0]; % End Position of Red Condiment

            qI = q0;

            
            if value(1) ==  "B"
                i = 3;
                qMatrix = jtraj(qI,q1,50);
                qMatrix2 = jtraj(q1,q2,50);
                qMatrix1 = jtraj(q1,qC{i},50);
                qMatrix3 = jtraj(q2,qE{i},50);
                qMatrix4 = jtraj(qE{i}, q2, 50);

                Movements_realDobot.move(qMatrix);
                Movements_realDobot.move(qMatrix1);

                Movements_realDobot.toolOnOff(app.dobot, 1);

                Movements_realDobot.move(qMatrix);
                Movements_realDobot.move(qMatrix2);
                Movements_realDobot.move(qMatrix3);

                Movements_realDobot.toolOnOff(app.dobot,0);

                Movements_realDobot.move(qMatrix4);
            elseif value(1) == "A"
                i = 2;
                qMatrix = jtraj(qI,q1,50);
                qMatrix2 = jtraj(q1,q2,50);
                qMatrix1 = jtraj(q1,qC{i},50);
                qMatrix3 = jtraj(q2,qE{i},50);
                qMatrix4 = jtraj(qE{i}, q2, 50);

                Movements_realDobot.move(qMatrix);
                Movements_realDobot.move(qMatrix1);

                Movements_realDobot.toolOnOff(app.dobot, 1);

                Movements_realDobot.move(qMatrix);
                Movements_realDobot.move(qMatrix2);
                Movements_realDobot.move(qMatrix3);

                Movements_realDobot.toolOnOff(app.dobot,0);

                Movements_realDobot.move(qMatrix4);
            elseif value(1) == "S"
                i = 1;
                qMatrix = jtraj(qI,q1,50);
                qMatrix2 = jtraj(q1,q2,50);
                qMatrix1 = jtraj(q1,qC{i},50);
                qMatrix3 = jtraj(q2,qE{i},50);
                qMatrix4 = jtraj(qE{i}, q2, 50);

                Movements_realDobot.move(qMatrix);
                Movements_realDobot.move(qMatrix1);

                Movements_realDobot.toolOnOff(app.dobot, 1);

                Movements_realDobot.move(qMatrix);
                Movements_realDobot.move(qMatrix2);
                Movements_realDobot.move(qMatrix3);

                Movements_realDobot.toolOnOff(app.dobot,0);

                Movements_realDobot.move(qMatrix4);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Q1SliderLabel = uilabel(app.UIFigure);
            app.Q1SliderLabel.Text = 'Q1';
            app.Q1Slider = uislider(app.UIFigure);
            app.Q1Slider.Limits = [-135 135];
            app.Q1Slider.ValueChangedFcn = createCallbackFcn(app, @Q1SliderValueChanged, true);
            app.Q2SliderLabel = uilabel(app.UIFigure);
            app.Q2SliderLabel.Text = 'Q2';
            app.Q2Slider = uislider(app.UIFigure);
            app.Q2Slider.Limits = [5 80];
            app.Q2Slider.ValueChangedFcn = createCallbackFcn(app, @Q2SliderValueChanged, true);
            app.Q2Slider.Value = 40;
            app.Q3SliderLabel = uilabel(app.UIFigure);
            app.Q3SliderLabel.Text = 'Q3';
            app.Q3Slider = uislider(app.UIFigure);
            app.Q3Slider.Limits = [15 170];
            app.Q3Slider.ValueChangedFcn = createCallbackFcn(app, @Q3SliderValueChanged, true);
            app.Q3Slider.Value = 95;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.CondimentOptionsDropDownLabel = uilabel(app.UIFigure);
            app.CondimentOptionsDropDownLabel.Text = 'Condiment Options';
            app.CondimentOptionsDropDown = uidropdown(app.UIFigure);
            app.CondimentOptionsDropDown.Items = {'None', 'Aioli Sauce', 'Sweet Sauce', 'BBQ Sauce'};
            app.CondimentOptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @CondimentOptionsDropDownValueChanged, true);
            app.CondimentOptionsDropDown.Value = 'None';
            app.PickUpTargetButton = uibutton(app.UIFigure, 'push');
            app.PickUpTargetButton.ButtonPushedFcn = createCallbackFcn(app, @PickUpTargetButtonPushed, true);
            app.PickUpTargetButton.Text = 'Pick Up Target';
            app.Q4SliderLabel = uilabel(app.UIFigure);
            app.Q4SliderLabel.Text = 'Q4';
            app.Q4Slider = uislider(app.UIFigure);
            app.Q4Slider.Limits = [-90 90];
            app.Q4Slider.ValueChangedFcn = createCallbackFcn(app, @Q4SliderValueChanged, true);
            app.Q5SliderLabel = uilabel(app.UIFigure);
            app.Q5SliderLabel.Text = 'Q5';
            app.Q5Slider = uislider(app.UIFigure);
            app.Q5Slider.Limits = [-85 85];
            app.Q5Slider.ValueChangedFcn = createCallbackFcn(app, @Q5SliderValueChanged, true);
            app.CondimentOptionsDropDownLabel_2 = uilabel(app.UIFigure);
            app.CondimentOptionsDropDownLabel_2.Text = 'DOBOT Joint Controls';
            app.EStopSwitchLabel = uilabel(app.UIFigure);
            app.EStopSwitchLabel.Text = 'EStop';
            app.EStopSwitch = uiswitch(app.UIFigure, 'slider');
            app.EStopSwitch.ValueChangedFcn = createCallbackFcn(app, @EStopSwitchValueChanged, true);
            app.StartSimulationButton = uibutton(app.UIFigure, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.CondimentOptionsDropDownLabel_3 = uilabel(app.UIFigure);
            app.CondimentOptionsDropDownLabel_3.Text = 'xArm Joint Controls';
            app.Q1Slider_2Label = uilabel(app.UIFigure);
            app.Q1Slider_2Label.Text = 'Q1';
            app.Q1Slider_2 = uislider(app.UIFigure);
            app.Q1Slider_2.Limits = [-360 360];
            app.Q1Slider_2.ValueChangedFcn = createCallbackFcn(app, @Q1Slider_2ValueChanged, true);
            app.Q2Slider_2Label = uilabel(app.UIFigure);
            app.Q2Slider_2Label.Text = 'Q2';
            app.Q2Slider_2 = uislider(app.UIFigure);
            app.Q2Slider_2.Limits = [-118 120];
            app.Q2Slider_2.ValueChangedFcn = createCallbackFcn(app, @Q2Slider_2ValueChanged, true);
            app.Q3Slider_2Label = uilabel(app.UIFigure);
            app.Q3Slider_2Label.Text = 'Q3';
            app.Q3Slider_2 = uislider(app.UIFigure);
            app.Q3Slider_2.Limits = [-225 11];
            app.Q3Slider_2.ValueChangedFcn = createCallbackFcn(app, @Q3Slider_2ValueChanged, true);
            app.Q3Slider_2.Value = -107;
            app.Q4Slider_2Label = uilabel(app.UIFigure);
            app.Q4Slider_2Label.Text = 'Q4';
            app.Q4Slider_2 = uislider(app.UIFigure);
            app.Q4Slider_2.Limits = [-97 180];
            app.Q4Slider_2.ValueChangedFcn = createCallbackFcn(app, @Q4Slider_2ValueChanged, true);
            app.Q4Slider_2.Value = 45;
            app.Q5Slider_2Label = uilabel(app.UIFigure);
            app.Q5Slider_2Label.Text = 'Q5';
            app.Q5Slider_2 = uislider(app.UIFigure);
            app.Q5Slider_2.Limits = [-360 360];
            app.Q5Slider_2.ValueChangedFcn = createCallbackFcn(app, @Q5Slider_2ValueChanged, true);
            app.DOBOTJoystickControlSwitchLabel = uilabel(app.UIFigure);
            app.DOBOTJoystickControlSwitchLabel.Text = 'DOBOT Joystick Control';
            app.DOBOTJoystickControlSwitch = uiswitch(app.UIFigure, 'toggle');
            app.DOBOTJoystickControlSwitch.ValueChangedFcn = createCallbackFcn(app, @DOBOTJoystickControlSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end