classdef GUITESTSAMPLE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ObstacleDropDown               matlab.ui.control.DropDown
        ObstacleDropDownLabel          matlab.ui.control.Label
        ONOFFObstacleButton            matlab.ui.control.Button
        Lamp_2                         matlab.ui.control.Lamp
        Label_2                        matlab.ui.control.Label
        ReturnZeroButton               matlab.ui.control.Button
        TargetDropDown                 matlab.ui.control.DropDown
        TargetDropDownLabel            matlab.ui.control.Label
        CondimentOptionsDropDown       matlab.ui.control.DropDown
        CondimentOptionsDropDownLabel  matlab.ui.control.Label
        EnvironmentButton              matlab.ui.control.Button
        ESTOPButton                    matlab.ui.control.Button
        Lamp                           matlab.ui.control.Lamp
        Label                          matlab.ui.control.Label
        Q3Slider                       matlab.ui.control.Slider
        Q3SliderLabel                  matlab.ui.control.Label
        Q2Slider                       matlab.ui.control.Slider
        Q2SliderLabel                  matlab.ui.control.Label
        Q1Slider                       matlab.ui.control.Slider
        Q1SliderLabel                  matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        arm; % Hold the dobot object
       
        target;
        obstacle;
        obstacleSide = 0.02;
        obstacleCenter = [0.1 -0.1 0.2];
        ifBlocked = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.arm = dobot;
            disp("Dobot is successfully loaded");
        end

        % Value changed function: Q1Slider
        function Q1SliderValueChanged(app, event)
              q = [];
              q(1) = deg2rad(app.Q1Slider.Value);
              q(2) = deg2rad(app.Q2Slider.Value);
              q(3) = deg2rad(app.Q3Slider.Value);
              q(4) = (pi/2) - q(2) - q(3);
              q(5) = 0;
              app.arm.model.animate(q);
        end

        % Value changed function: Q2Slider
        function Q2SliderValueChanged(app, event)
              q = [];
              q(1) = deg2rad(app.Q1Slider.Value);
              q(2) = deg2rad(app.Q2Slider.Value);
              q(3) = deg2rad(app.Q3Slider.Value);
              q(4) = (pi/2) - q(2) - q(3);
              q(5) = 0;
              app.arm.model.animate(q);    
        end

        % Value changed function: Q3Slider
        function Q3SliderValueChanged(app, event)
              q = [];
              q(1) = deg2rad(app.Q1Slider.Value);
              q(2) = deg2rad(app.Q2Slider.Value);
              q(3) = deg2rad(app.Q3Slider.Value);
              q(4) = (pi/2) - q(2) - q(3);
              q(5) = 0;
              app.arm.model.animate(q);       
        end

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
            % Change the LED color depending on the ESTOP stage
            app.arm.PressESTOP;
            if app.arm.ESTOP == true
                app.Lamp.Color = 'red';     
            end
            if app.arm.ESTOP == false
                app.Lamp.Color = 'green';
            end
        end

        % Button pushed function: EnvironmentButton
        function EnvironmentButtonPushed(app, event)
            % Setup the environment
            
        end

        % Value changed function: CondimentOptionsDropDown
        function CondimentOptionsDropDownValueChanged(app, event)
            value = app.CondimentOptionsDropDown.Value;
            if value == "On"
                app.arm.LightCurtain;
            end
            if value == "Off"
                app.arm.TurnOffCurtain;
            end
        end

        % Button pushed function: ReturnZeroButton
        function ReturnZeroButtonPushed(app, event)
            app.arm.ReturnZero;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Q1SliderLabel = uilabel(app.UIFigure);
            app.Q1SliderLabel.Text = 'Q1';
            app.Q1Slider = uislider(app.UIFigure);
            app.Q1Slider.Limits = [-135 135];
            app.Q1Slider.ValueChangedFcn = createCallbackFcn(app, @Q1SliderValueChanged, true);
            app.Q2SliderLabel = uilabel(app.UIFigure);
            app.Q2SliderLabel.Text = 'Q2';
            app.Q2Slider = uislider(app.UIFigure);
            app.Q2Slider.Limits = [5 80];
            app.Q2Slider.ValueChangedFcn = createCallbackFcn(app, @Q2SliderValueChanged, true);
            app.Q2Slider.Value = 45;
            app.Q3SliderLabel = uilabel(app.UIFigure);
            app.Q3SliderLabel.Text = 'Q3';
            app.Q3Slider = uislider(app.UIFigure);
            app.Q3Slider.Limits = [15 170];
            app.Q3Slider.ValueChangedFcn = createCallbackFcn(app, @Q3SliderValueChanged, true);
            app.Q3Slider.Value = 90;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Text = 'ESTOP';
            app.EnvironmentButton = uibutton(app.UIFigure, 'push');
            app.EnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @EnvironmentButtonPushed, true);
            app.EnvironmentButton.Text = 'Environment';
            app.CondimentOptionsDropDownLabel = uilabel(app.UIFigure);
            app.CondimentOptionsDropDownLabel.Text = 'Condiment Options';
            app.CondimentOptionsDropDown = uidropdown(app.UIFigure);
            app.CondimentOptionsDropDown.Items = {'None', 'Ketchup', 'Mayonaise', 'Sweet Sauce', 'BBQ Sauce', ''};
            app.CondimentOptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @CondimentOptionsDropDownValueChanged, true);
            app.CondimentOptionsDropDown.Value = 'None';
            app.TargetDropDownLabel = uilabel(app.UIFigure);
            app.TargetDropDownLabel.Text = 'Target';
            app.TargetDropDown = uidropdown(app.UIFigure);
            app.TargetDropDown.Items = {'None', 'Coin 1', 'Coin 2', 'Coin 3'};
            app.TargetDropDown.Value = 'None';
            app.ReturnZeroButton = uibutton(app.UIFigure, 'push');
            app.ReturnZeroButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnZeroButtonPushed, true);
            app.ReturnZeroButton.Text = 'Return Zero';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.ONOFFObstacleButton = uibutton(app.UIFigure, 'push');
            app.ONOFFObstacleButton.Text = 'ON/OFF Obstacle';
            app.ObstacleDropDownLabel = uilabel(app.UIFigure);
            app.ObstacleDropDownLabel.Text = 'Obstacle';
            app.ObstacleDropDown = uidropdown(app.UIFigure);
            app.ObstacleDropDown.Items = {'None', 'A', 'B', 'C'};
            app.ObstacleDropDown.Value = 'None';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUITESTSAMPLE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end